classdef AppControlForwardMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ControlPanelForwardKinematicVisualizationLabel  matlab.ui.control.Label
        GridLayout3           matlab.ui.container.GridLayout
        GridLayout5           matlab.ui.container.GridLayout
        EditField_8           matlab.ui.control.NumericEditField
        HTML                  matlab.ui.control.HTML
        Slider                matlab.ui.control.Slider
        GridLayout4           matlab.ui.container.GridLayout
        MetricsDropDown       matlab.ui.control.DropDown
        MetricsDropDownLabel  matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        EditField_7           matlab.ui.control.NumericEditField
        EditField_6           matlab.ui.control.NumericEditField
        EditField_5           matlab.ui.control.NumericEditField
        EditField_4           matlab.ui.control.NumericEditField
        EditField_3           matlab.ui.control.NumericEditField
        EditField_2           matlab.ui.control.NumericEditField
        EditField             matlab.ui.control.NumericEditField
        q7Slider              matlab.ui.control.Slider
        q7SliderLabel         matlab.ui.control.Label
        q6Slider              matlab.ui.control.Slider
        q6SliderLabel         matlab.ui.control.Label
        q5Slider              matlab.ui.control.Slider
        q5SliderLabel         matlab.ui.control.Label
        q4Slider              matlab.ui.control.Slider
        q4SliderLabel         matlab.ui.control.Label
        q3Slider              matlab.ui.control.Slider
        q3SliderLabel         matlab.ui.control.Label
        q2Slider              matlab.ui.control.Slider
        q2SliderLabel         matlab.ui.control.Label
        q1Slider              matlab.ui.control.Slider
        q1SliderLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        forwardMap(1,1)     % Forward Map data
        TR                  % forward Map triangularzation
        metricMapCell cell  % the cell for displaying metrics in the inverseMap 
        dataMetric          % the data for metric
        valueMetric = 0     % the metric display for data

        discretizeArray(:,:) % array for discretization

        qValue(7,1) double % the joint value
        qValueBuffer(7,1) double

        TValue(4,4) double % the EE frame from Map

        model(1,1) % the data model of the result

        callbackMutex(1,1) logical = false % true to not to respond to callback
        updatemutex(1,1) logical = false
    end
    
    
    methods (Access = public)
        function inputNormalizeAndUpdate(app, qInput)
            arrayDiff = app.discretizeArray - qInput;
            arrayNorm = arrayDiff.^2;
            [~,arrayMinI] = min(arrayNorm,[],2);

            qNormalized = zeros(7,1);
            for iQ = 1:7
                qNormalized(iQ) = app.discretizeArray(iQ,arrayMinI(iQ));
            end

            app.qValue = qNormalized;
            app.TValue = app.forwardMap.Codomain.data(:,:, ...
                arrayMinI(1),arrayMinI(2),arrayMinI(3),arrayMinI(4),arrayMinI(5),arrayMinI(6),arrayMinI(7));
            if isequal(app.Slider.Enable,"on")
                app.valueMetric = app.dataMetric(:,arrayMinI(1),arrayMinI(2),arrayMinI(3),arrayMinI(4),arrayMinI(5),arrayMinI(6),arrayMinI(7));
            end
        end
        
        function updateModel(app)
            if app.updatemutex == false
                app.updatemutex = true;
                app.model.updateData('q',app.qValue);
                app.model.updateData('T_EE',app.TValue)
                app.updatemutex = false;
            end
        end

        function updateSliderDisplay(app)
            app.q1Slider.Value = app.qValue(1);
            app.q2Slider.Value = app.qValue(2);
            app.q3Slider.Value = app.qValue(3);
            app.q4Slider.Value = app.qValue(4);
            app.q5Slider.Value = app.qValue(5);
            app.q6Slider.Value = app.qValue(6);
            app.q7Slider.Value = app.qValue(7);
            app.Slider.Value = app.valueMetric;
        end

        function updateEditFieldDisplay(app)
            app.EditField.Value = app.qValue(1);
            app.EditField_2.Value = app.qValue(2);
            app.EditField_3.Value = app.qValue(3);
            app.EditField_4.Value = app.qValue(4);
            app.EditField_5.Value = app.qValue(5);
            app.EditField_6.Value = app.qValue(6);
            app.EditField_7.Value = app.qValue(7);
            app.EditField_8.Value = app.valueMetric;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, forwardMap, model, metricMapCell)
            app.forwardMap = forwardMap;
            app.model = model;
            app.discretizeArray = forwardMap.Domain.metadata.discretizeArray;
            
            T_data = forwardMap.Codomain.getDataReshaped();
            x = squeeze(T_data(1, 4, :));
            y = squeeze(T_data(2, 4, :));
            z = squeeze(T_data(3, 4, :));
            k = convhull(x, y, z, 'Simplify', true);
            warning("off");
            app.TR = triangulation(k, x, y, z);
            warning("on");
            app.model.updateData('convex_hull',app.TR);
            app.model.setVisibility('convex_hull',true);

            joint = 1;
            app.q1Slider.MajorTicks = app.discretizeArray(joint,:);
            app.q1Slider.MajorTickLabels = string(app.discretizeArray(joint,:));
            app.q1Slider.Limits = [app.discretizeArray(joint,1), app.discretizeArray(joint,end)];
            joint = 2;
            app.q2Slider.MajorTicks = app.discretizeArray(joint,:);
            app.q2Slider.MajorTickLabels = string(app.discretizeArray(joint,:));
            app.q2Slider.Limits = [app.discretizeArray(joint,1), app.discretizeArray(joint,end)];
            joint = 3;
            app.q3Slider.MajorTicks = app.discretizeArray(joint,:);
            app.q3Slider.MajorTickLabels = string(app.discretizeArray(joint,:));
            app.q3Slider.Limits = [app.discretizeArray(joint,1), app.discretizeArray(joint,end)];
            joint = 4;
            app.q4Slider.MajorTicks = app.discretizeArray(joint,:);
            app.q4Slider.MajorTickLabels = string(app.discretizeArray(joint,:));
            app.q4Slider.Limits = [app.discretizeArray(joint,1), app.discretizeArray(joint,end)];
            joint = 5;
            app.q5Slider.MajorTicks = app.discretizeArray(joint,:);
            app.q5Slider.MajorTickLabels = string(app.discretizeArray(joint,:));
            app.q5Slider.Limits = [app.discretizeArray(joint,1), app.discretizeArray(joint,end)];
            joint = 6;
            app.q6Slider.MajorTicks = app.discretizeArray(joint,:);
            app.q6Slider.MajorTickLabels = string(app.discretizeArray(joint,:));
            app.q6Slider.Limits = [app.discretizeArray(joint,1), app.discretizeArray(joint,end)];
            joint = 7;
            app.q7Slider.MajorTicks = app.discretizeArray(joint,:);
            app.q7Slider.MajorTickLabels = string(app.discretizeArray(joint,:));
            app.q7Slider.Limits = [app.discretizeArray(joint,1), app.discretizeArray(joint,end)];

            % metricMapCell will be a map that contain metric map as a cell  
            app.metricMapCell = metricMapCell;
            for iMap = 1:numel(app.metricMapCell)
                app.MetricsDropDown.Items = [app.MetricsDropDown.Items,char(app.metricMapCell{iMap}.Codomain.extraData.name)]; 
            end
            app.Slider.Enable = "off";

            app.HTML.HTMLSource = fullfile(pwd,"src","app","apputils","colormap.html");
            app.Slider.Limits = [0,1];

            app.model.setVisibility('voxel_position',false);

            app.qValue = app.discretizeArray(:,1);
            app.updateModel();
            app.updateEditFieldDisplay();
            app.updateSliderDisplay();
        end

        % Value changed function: EditField, EditField_2, EditField_3, 
        % ...and 4 other components
        function InputValueChanged(app, event)
            if app.callbackMutex == false
                app.callbackMutex = true;
                qValueTemp = [app.EditField.Value;
                              app.EditField_2.Value;
                              app.EditField_3.Value;
                              app.EditField_4.Value;
                              app.EditField_5.Value;
                              app.EditField_6.Value;
                              app.EditField_7.Value];
    
                app.inputNormalizeAndUpdate(qValueTemp);
    
                app.updateModel();
                app.updateEditFieldDisplay();
                app.updateSliderDisplay();
                app.qValueBuffer = qValueTemp;

                app.callbackMutex = false;
            end
        end

        % Value changing function: q1Slider, q2Slider, q3Slider, q4Slider, 
        % ...and 3 other components
        function SliderValueChanging(app, event)
            if app.callbackMutex == false
                app.callbackMutex = true;
                qValueTemp = [app.q1Slider.Value;
                              app.q2Slider.Value;
                              app.q3Slider.Value;
                              app.q4Slider.Value;
                              app.q5Slider.Value;
                              app.q6Slider.Value;
                              app.q7Slider.Value];
                if isequal(event.Source,app.q1Slider)
                    qValueTemp(1) = event.Value;
                end
                if isequal(event.Source,app.q2Slider)
                    qValueTemp(2) = event.Value;
                end
                if isequal(event.Source,app.q3Slider)
                    qValueTemp(3) = event.Value;
                end
                if isequal(event.Source,app.q4Slider)
                    qValueTemp(4) = event.Value;
                end
                if isequal(event.Source,app.q5Slider)
                    qValueTemp(5) = event.Value;
                end
                if isequal(event.Source,app.q6Slider)
                    qValueTemp(6) = event.Value;
                end
                if isequal(event.Source,app.q7Slider)
                    qValueTemp(7) = event.Value;
                end
    
                app.inputNormalizeAndUpdate(qValueTemp);
    
                if ~isequal(app.qValueBuffer,qValueTemp)
                    app.updateModel();
                    app.updateEditFieldDisplay();
                    app.qValueBuffer = qValueTemp;
                end
                app.callbackMutex = false;
            end
        end

        % Value changed function: q1Slider, q2Slider, q3Slider, q4Slider, 
        % ...and 3 other components
        function SliderValueChanged(app, event)
            if app.callbackMutex == false
                app.callbackMutex = true;
                qValueTemp = [app.q1Slider.Value;
                              app.q2Slider.Value;
                              app.q3Slider.Value;
                              app.q4Slider.Value;
                              app.q5Slider.Value;
                              app.q6Slider.Value;
                              app.q7Slider.Value];
    
                app.inputNormalizeAndUpdate(qValueTemp);
    
                app.updateModel();
                app.updateEditFieldDisplay();
                app.updateSliderDisplay();
                app.qValueBuffer = qValueTemp;

                app.callbackMutex = false;
            end
        end

        % Value changed function: MetricsDropDown
        function MetricsDropDownValueChanged(app, event)
            value = app.MetricsDropDown.Value;
            for iItem = 1:numel(app.MetricsDropDown.Items)
                if isequal(value,'none')
                    app.Slider.Enable = "off";
                end
                if isequal(value,app.MetricsDropDown.Items{iItem}) && ~isequal(value,'none')
                    app.Slider.Enable = "on";
                    app.dataMetric = app.metricMapCell{iItem-1}.Codomain.data;
                    app.Slider.Limits = [min(app.dataMetric,[],'all'),max(app.dataMetric,[],'all')];
                    break;
                end
            end
        end

        % Value changed function: Slider
        function MetricsSliderChanges(app, event)
            app.Slider.Value = app.valueMetric;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.q1SliderLabel = uilabel(app.GridLayout2);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.GridLayout2);
            app.q1Slider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.q1Slider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.q1Slider.MinorTicks = [];
            app.q2SliderLabel = uilabel(app.GridLayout2);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.GridLayout2);
            app.q2Slider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.q2Slider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.q2Slider.MinorTicks = [];
            app.q3SliderLabel = uilabel(app.GridLayout2);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.GridLayout2);
            app.q3Slider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.q3Slider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.q3Slider.MinorTicks = [];
            app.q4SliderLabel = uilabel(app.GridLayout2);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.GridLayout2);
            app.q4Slider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.q4Slider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.q4Slider.MinorTicks = [];
            app.q5SliderLabel = uilabel(app.GridLayout2);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.GridLayout2);
            app.q5Slider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.q5Slider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.q5Slider.MinorTicks = [];
            app.q6SliderLabel = uilabel(app.GridLayout2);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.GridLayout2);
            app.q6Slider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.q6Slider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.q6Slider.MinorTicks = [];
            app.q7SliderLabel = uilabel(app.GridLayout2);
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.GridLayout2);
            app.q7Slider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.q7Slider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.q7Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.q7Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.q7Slider.MinorTicks = [];
            app.EditField = uieditfield(app.GridLayout2, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.EditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.EditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.EditField_4 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.EditField_5 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.EditField_6 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.EditField_7 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.MetricsDropDownLabel = uilabel(app.GridLayout4);
            app.MetricsDropDownLabel.Text = 'Metrics';
            app.MetricsDropDown = uidropdown(app.GridLayout4);
            app.MetricsDropDown.Items = {'none'};
            app.MetricsDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricsDropDownValueChanged, true);
            app.MetricsDropDown.Value = 'none';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.Slider = uislider(app.GridLayout5);
            app.Slider.Limits = [0 0.1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @MetricsSliderChanges, true);
            app.Slider.MinorTicks = [];
            app.HTML = uihtml(app.GridLayout5);
            app.EditField_8 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_8.Editable = 'off';
            app.ControlPanelForwardKinematicVisualizationLabel = uilabel(app.GridLayout);
            app.ControlPanelForwardKinematicVisualizationLabel.Text = 'Control Panel: Forward Kinematic Visualization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppControlForwardMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end