classdef AppControlInverseMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        GridLayout3      matlab.ui.container.GridLayout
        GridLayout4      matlab.ui.container.GridLayout
        HTML             matlab.ui.control.HTML
        EditField_2      matlab.ui.control.NumericEditField
        Slider           matlab.ui.control.Slider
        GridLayout5      matlab.ui.container.GridLayout
        MetricsLabel     matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        GridLayout2      matlab.ui.container.GridLayout
        zSpinner         matlab.ui.control.Spinner
        ySpinner         matlab.ui.control.Spinner
        xSpinner         matlab.ui.control.Spinner
        flipSlider       matlab.ui.control.Slider
        flipSliderLabel  matlab.ui.control.Label
        secSpinner       matlab.ui.control.Spinner
        rotSpinner       matlab.ui.control.Spinner
        dirSpinner       matlab.ui.control.Spinner
        flipSpinner      matlab.ui.control.Spinner
        secSlider        matlab.ui.control.Slider
        secSliderLabel   matlab.ui.control.Label
        rotSlider        matlab.ui.control.Slider
        rotSliderLabel   matlab.ui.control.Label
        dirSlider        matlab.ui.control.Slider
        dirSliderLabel   matlab.ui.control.Label
        zSlider          matlab.ui.control.Slider
        zSliderLabel     matlab.ui.control.Label
        ySlider          matlab.ui.control.Slider
        ySliderLabel     matlab.ui.control.Label
        xSlider          matlab.ui.control.Slider
        xSliderLabel     matlab.ui.control.Label
        ControlPanelInverseKinematicVisualizationLabel  matlab.ui.control.Label
    end

    
    properties (SetAccess = private)
        inverseMap (1,1)
        TR
        metricMapCell cell
        dataMetric
        valueMetric = 0

        positionList
        
        inputValue(7,1) double
        inputBuffer(7,1) double

        qValue(7,1) double
        TValue(4,4) double

        model(1,1) % the data model of the result

        callbackMutex(1,1) logical = false
        updatemutex(1,1) logical = false
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        function inputNormalizeAndUpdate(app, input)
            arrayPosDiff = app.positionList - input(1:3);
            arrayPosNorm = sum(arrayPosDiff.^2, 1);
            [~,arrayMinPosI] = min(arrayPosNorm,[], 'all');

            dirI = round(input(4));
            rotI = round(input(5));
            secI = round(input(6));
            flipI = round(input(7));
            
            app.inputValue = [app.positionList(:,arrayMinPosI);dirI;rotI;secI;flipI];
            app.qValue = app.inverseMap.Codomain.data(:,arrayMinPosI, dirI, rotI, secI, flipI);
            app.TValue = app.inverseMap.Domain.data(:, :, arrayMinPosI, dirI, rotI);
            if isequal(app.Slider.Enable,"on")
                app.valueMetric = app.dataMetric(:,arrayMinPosI, dirI, rotI, secI, flipI);
            end
        end

        function updateModel(app)
            if app.updatemutex == false
                app.updatemutex = true;
                app.model.updateData('q',app.qValue);
                app.model.updateData('T_EE',app.TValue)
                app.updatemutex = false;
            end
        end
        
        function updateSliderDisplay(app)
            app.xSlider.Value = app.inputValue(1);
            app.ySlider.Value = app.inputValue(2);
            app.zSlider.Value = app.inputValue(3);
            app.dirSlider.Value = app.inputValue(4);
            app.rotSlider.Value = app.inputValue(5);
            app.secSlider.Value = app.inputValue(6);
            app.flipSlider.Value = app.inputValue(7);
            app.Slider.Value = app.valueMetric;
        end

        function updateSpinnerAndFieldDisplay(app)
            app.xSpinner.Value = app.inputValue(1);
            app.ySpinner.Value = app.inputValue(2);
            app.zSpinner.Value = app.inputValue(3);
            app.dirSpinner.Value = app.inputValue(4);
            app.rotSpinner.Value = app.inputValue(5);
            app.secSpinner.Value = app.inputValue(6);
            app.flipSpinner.Value = app.inputValue(7);
            app.EditField_2.Value = app.valueMetric;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inverseMap, model, metricMapCell)
            app.inverseMap = inverseMap;
            app.model = model;
            app.positionList = app.inverseMap.Domain.metadata.position';

            % get the forward map of the 
            T_data = inverseMap.Domain.extraData.forwardMap.Codomain.getDataReshaped();
            x = squeeze(T_data(1, 4, :));
            y = squeeze(T_data(2, 4, :));
            z = squeeze(T_data(3, 4, :));
            k = convhull(x, y, z, 'Simplify', true);
            warning("off");
            app.TR = triangulation(k, x, y, z);
            warning("on");
            app.model.updateData('convex_hull',app.TR);
            app.model.setVisibility('convex_hull',true);

            valueSorted = unique(app.positionList(1,:));
            app.xSlider.MajorTicks = valueSorted;
            app.xSlider.MajorTickLabels = string(valueSorted);
            app.xSlider.Limits = [valueSorted(1), valueSorted(end)];
            app.xSpinner.Limits = [valueSorted(1), valueSorted(end)];
            app.xSpinner.Step = valueSorted(2) - valueSorted(1);

            valueSorted = unique(app.positionList(2,:));
            app.ySlider.MajorTicks = valueSorted;
            app.ySlider.MajorTickLabels = string(valueSorted);
            app.ySlider.Limits = [valueSorted(1), valueSorted(end)];
            app.ySpinner.Limits = [valueSorted(1), valueSorted(end)];
            app.ySpinner.Step = valueSorted(2) - valueSorted(1);

            valueSorted = unique(app.positionList(3,:));
            app.zSlider.MajorTicks = valueSorted;
            app.zSlider.MajorTickLabels = string(valueSorted);
            app.zSlider.Limits = [valueSorted(1), valueSorted(end)];
            app.zSpinner.Limits = [valueSorted(1), valueSorted(end)];
            app.zSpinner.Step = valueSorted(2) - valueSorted(1);
            
            valueSorted = 1:app.inverseMap.Codomain.sizeIndex(2);
            app.dirSlider.MajorTicks = valueSorted;
            app.dirSlider.MajorTickLabels = string(valueSorted);
            app.dirSlider.Limits = [valueSorted(1), valueSorted(end)];
            app.dirSpinner.Limits = [valueSorted(1), valueSorted(end)];

            valueSorted = 1:app.inverseMap.Codomain.sizeIndex(3);
            app.rotSlider.MajorTicks = valueSorted;
            app.rotSlider.MajorTickLabels = string(valueSorted);
            app.rotSlider.Limits = [valueSorted(1), valueSorted(end)];
            app.rotSpinner.Limits = [valueSorted(1), valueSorted(end)];

            valueSorted = 1:app.inverseMap.Codomain.sizeIndex(4);
            app.secSlider.MajorTicks = valueSorted;
            app.secSlider.MajorTickLabels = string(valueSorted);
            app.secSlider.Limits = [valueSorted(1), valueSorted(end)];
            app.secSpinner.Limits = [valueSorted(1), valueSorted(end)];

            % metricMapCell will be a map that contain metric map as a cell  
            app.metricMapCell = metricMapCell;
            for iMap = 1:numel(app.metricMapCell)
                app.DropDown.Items = [app.DropDown.Items,char(app.metricMapCell{iMap}.Codomain.extraData.name)]; 
            end
            app.Slider.Enable = "off";

            app.HTML.HTMLSource = fullfile(pwd,"src","app","apputils","colormap.html");
            app.Slider.Limits = [0,1];

            % load the position scattering in model data
            app.model.updateData('voxel_position',app.positionList);
            app.model.setVisibility('voxel_position',true);
        end

        % Value changed function: dirSlider, rotSlider, secSlider, 
        % ...and 3 other components
        function SliderValueChanged(app, event)
            if app.callbackMutex == false
                app.callbackMutex = true;
                inputTemp = [app.xSlider.Value;
                             app.ySlider.Value;
                             app.zSlider.Value;
                             app.dirSlider.Value;
                             app.rotSlider.Value;
                             app.secSlider.Value;
                             app.flipSlider.Value];

                app.inputNormalizeAndUpdate(inputTemp);

                app.updateModel();
                app.updateSpinnerAndFieldDisplay();
                app.updateSliderDisplay();
                app.inputBuffer = inputTemp;

                app.callbackMutex = false;
            end
        end

        % Value changing function: dirSlider, rotSlider, secSlider, 
        % ...and 3 other components
        function SliderValueChanging(app, event)
            if app.callbackMutex == false
                app.callbackMutex = true;
                inputTemp = [app.xSlider.Value;
                             app.ySlider.Value;
                             app.zSlider.Value;
                             app.dirSlider.Value;
                             app.rotSlider.Value;
                             app.secSlider.Value;
                             app.flipSlider.Value];
                if isequal(event.Source,app.xSlider)
                    inputTemp(1) = event.Value;
                end
                if isequal(event.Source,app.ySlider)
                    inputTemp(2) = event.Value;
                end
                if isequal(event.Source,app.zSlider)
                    inputTemp(3) = event.Value;
                end
                if isequal(event.Source,app.dirSlider)
                    inputTemp(4) = event.Value;
                end
                if isequal(event.Source,app.rotSlider)
                    inputTemp(5) = event.Value;
                end
                if isequal(event.Source,app.secSlider)
                    inputTemp(6) = event.Value;
                end
                if isequal(event.Source,app.flipSlider)
                    inputTemp(7) = event.Value;
                end

                app.inputNormalizeAndUpdate(inputTemp);

                if ~isequal(app.inputBuffer,inputTemp)
                    app.updateModel();
                    app.updateSpinnerAndFieldDisplay();
                    app.updateSliderDisplay();
                    app.inputBuffer = inputTemp;
                end

                app.callbackMutex = false;
            end
        end

        % Value changed function: dirSpinner, rotSpinner, secSpinner, 
        % ...and 3 other components
        function SpinnerValueChanged(app, event)
            if app.callbackMutex == false
                app.callbackMutex = true;
                inputTemp = [app.xSpinner.Value;
                             app.ySpinner.Value;
                             app.zSpinner.Value;
                             app.dirSpinner.Value;
                             app.rotSpinner.Value;
                             app.secSpinner.Value;
                             app.flipSpinner.Value];

                app.inputNormalizeAndUpdate(inputTemp);

                app.updateModel();
                app.updateSpinnerAndFieldDisplay();
                app.updateSliderDisplay();
                app.inputBuffer = inputTemp;

                app.callbackMutex = false;
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            for iItem = 1:numel(app.DropDown.Items)
                if isequal(value,'none')
                    app.Slider.Enable = "off";
                    app.model.setVisibility('voxel_value',false);
                end
                if isequal(value,app.DropDown.Items{iItem}) && ~isequal(value,'none')
                    app.Slider.Enable = "on";
                    app.model.setVisibility('voxel_value',true);
                    app.dataMetric = app.metricMapCell{iItem-1}.Codomain.data;
                    app.Slider.Limits = [min(app.dataMetric,[],'all'),max(app.dataMetric,[],'all')];
                    break;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ControlPanelInverseKinematicVisualizationLabel = uilabel(app.GridLayout);
            app.ControlPanelInverseKinematicVisualizationLabel.Text = 'Control Panel: Inverse Kinematic Visualization';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.xSliderLabel = uilabel(app.GridLayout2);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.GridLayout2);
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.xSlider.MinorTicks = [];
            app.ySliderLabel = uilabel(app.GridLayout2);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.GridLayout2);
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ySlider.MinorTicks = [];
            app.zSliderLabel = uilabel(app.GridLayout2);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.GridLayout2);
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.zSlider.MinorTicks = [];
            app.dirSliderLabel = uilabel(app.GridLayout2);
            app.dirSliderLabel.Text = 'dir';
            app.dirSlider = uislider(app.GridLayout2);
            app.dirSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.dirSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.dirSlider.MinorTicks = [];
            app.rotSliderLabel = uilabel(app.GridLayout2);
            app.rotSliderLabel.Text = 'rot';
            app.rotSlider = uislider(app.GridLayout2);
            app.rotSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rotSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.rotSlider.MinorTicks = [];
            app.secSliderLabel = uilabel(app.GridLayout2);
            app.secSliderLabel.Text = 'sec';
            app.secSlider = uislider(app.GridLayout2);
            app.secSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.secSlider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.secSlider.MinorTicks = [];
            app.flipSpinner = uispinner(app.GridLayout2);
            app.flipSpinner.Limits = [1 1];
            app.flipSpinner.Value = 1;
            app.dirSpinner = uispinner(app.GridLayout2);
            app.dirSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.dirSpinner.Value = 1;
            app.rotSpinner = uispinner(app.GridLayout2);
            app.rotSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.rotSpinner.Value = 1;
            app.secSpinner = uispinner(app.GridLayout2);
            app.secSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.secSpinner.Value = 1;
            app.flipSliderLabel = uilabel(app.GridLayout2);
            app.flipSliderLabel.Text = 'flip';
            app.flipSlider = uislider(app.GridLayout2);
            app.flipSlider.Limits = [0 2];
            app.flipSlider.MajorTicks = [0 1 2];
            app.flipSlider.MajorTickLabels = {' ', '1', ' '};
            app.flipSlider.MinorTicks = [];
            app.flipSlider.Enable = 'off';
            app.flipSlider.Value = 1;
            app.xSpinner = uispinner(app.GridLayout2);
            app.xSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ySpinner = uispinner(app.GridLayout2);
            app.ySpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.zSpinner = uispinner(app.GridLayout2);
            app.zSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.DropDown = uidropdown(app.GridLayout5);
            app.DropDown.Items = {'none'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'none';
            app.MetricsLabel = uilabel(app.GridLayout5);
            app.MetricsLabel.Text = 'Metrics:';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Slider = uislider(app.GridLayout4);
            app.Slider.MinorTicks = [];
            app.EditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.HTML = uihtml(app.GridLayout4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppControlInverseMap(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end